classdef burst_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RegressionlineYDXELabel      matlab.ui.control.Label
        edt_outlier                  matlab.ui.control.NumericEditField
        outliernstdEditFieldLabel    matlab.ui.control.Label
        lbl_work                     matlab.ui.control.Label
        sldr_yp                      matlab.ui.control.Slider
        dyLabel                      matlab.ui.control.Label
        sldr_xp                      matlab.ui.control.Slider
        slopeLabel                   matlab.ui.control.Label
        confirmcloseButton           matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        edt_n_std                    matlab.ui.control.NumericEditField
        nstdCLabel                   matlab.ui.control.Label
        edt_sec_width                matlab.ui.control.NumericEditField
        sectionwidthBLabel           matlab.ui.control.Label
        extentwindowAEditField       matlab.ui.control.NumericEditField
        extentwindowAEditFieldLabel  matlab.ui.control.Label
        ax_slope                     matlab.ui.control.UIAxes
        ax_amp                       matlab.ui.control.UIAxes
        ax_shapes                    matlab.ui.control.UIAxes
        ax_thresh                    matlab.ui.control.UIAxes
        ax_summs                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        caller
        data
        burst_res
        settings
    end
    
    methods (Access = public)
        
        function brsts = get_bursts(app)

            brsts= nan(length(app.data.evnt_idx),7);
            tmp_data = [app.data.x,zeros(1,3*app.data.fs)];
            for i = 1:length(app.data.evnt_idx)
                [~,tmp_pk] = max(app.data.x_filt(app.data.evnt_idx(i)+app.settings.window(1):app.data.evnt_idx(i)+app.settings.window(2)));

                switch app.settings.type
                    case 1
                        if (tmp_pk ~= 1) & (tmp_pk ~= (abs(diff(app.settings.window))+1))
                            tmp_idx = find(app.data.diff_x_filt(app.data.evnt_idx(i)+app.settings.window(1):app.data.evnt_idx(i)+app.settings.window(2))<0);
                            strt = find(tmp_idx<tmp_pk,1,'last');
                            stp =  find(tmp_idx>tmp_pk,1,'first');
                            strt= tmp_idx(strt);
                            if isempty(strt)
                                strt =1;
                            end
                            stp = tmp_idx(stp);
                            if isempty(stp)
                                stp = app.settings.window(2)-app.settings.window(1)+1;
                            end
                            brsts(i,1:3) = [strt-1+app.data.evnt_idx(i)+app.settings.window(1), tmp_pk-1+app.data.evnt_idx(i)+app.settings.window(1), stp-1+app.data.evnt_idx(i)+app.settings.window(1)];
                            brsts(i,4) = max(tmp_data(brsts(i,2))-tmp_data(brsts(i,1)),tmp_data(brsts(i,2))-tmp_data(brsts(i,3)));
                            brsts(i,5) = mean(diff(tmp_data(brsts(i,1):brsts(i,2))));
                            brsts(i,6) = mean(diff(tmp_data(brsts(i,2):brsts(i,3))));
                            brsts(i,7) = (brsts(i,2)-app.data.evnt_idx(i)+1)/app.data.fs;
                        end
                    case 2

                end 
                
            end
            
            brsts(isnan(brsts(:,3)),:) = [];
            
            brsts((brsts(:,1)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,2)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,3)>= length(app.data.x)),:) = [];

            idx = find(diff(brsts(:,2)) == 0);

            for i = 1: length(idx)
                brsts(idx(i),3) = brsts(idx(i)+1,3);
                [~, idx_mn] =  min ([abs(brsts(idx(i),7)-app.settings.delay),abs(brsts(idx(i)+1,7)-app.settings.delay)]);

                if idx_mn == 2
                    brsts(idx(i),7) = brsts(idx(i)+1,7);
                end
                brsts(idx(i),4) = max(tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),1)),tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),3)));
                brsts(idx(i),5) = mean(diff(tmp_data(brsts(idx(i),1):brsts(idx(i),2))));
                brsts(idx(i),6) = mean(diff(tmp_data(brsts(idx(i),2):brsts(idx(i),3))));
                brsts(idx(i)+1,:) = nan;
                
            end
            brsts(isnan(brsts(:,1)),:) = [];
            app.burst_res = brsts;
            % brsts(:,1)= start;
            % brsts(:,2)= peak; 
            % brsts(:,3)= end; 
            % brsts(:,4)= amplitude; 
            % brsts(:,5)= mean slope rise; 
            % brsts(:,6)= mean slope fall;
            % brsts(:,7)= burst delay;
        end
        
        function select_bursts(app,set)
            
            xp = app.burst_res(:,4); % Independent variable
            yp = app.burst_res(:,7); % Dependent variable
            app.settings.yl = [floor(min(yp*10))/10 ,ceil(max(yp*10))/10];
            app.settings.xl = [0 max(xp)*1.15];

            
            if set
                coefficients = [app.sldr_xp.Value, app.sldr_yp.Value];
            else
                % Fit a linear model
                coefficients = polyfit(xp, yp, 1); % 1st-degree polynomial (linear regression)
                app.sldr_xp.Limits  = [-.7, .7];
                app.sldr_xp.Value  = coefficients(1);
                
                app.sldr_yp.Limits = app.settings.yl;
                app.sldr_yp.Value = coefficients(2);
                
            end
            
            % Generate fitted values
            x_fit = linspace(min(xp), max(xp), 100); % Generate more points for smooth plotting
            y_fit = polyval(coefficients, x_fit);
            
            % Compute distances from the regression line
            y_pred = polyval(coefficients, xp);
            distances = abs(yp - y_pred);
            
            dist = app.edt_sec_width.Value; % Define distance threshold %% <=== change that
            selected_points = (distances <= dist);
            app.burst_res(:,9)= selected_points;
            selected_x = xp(selected_points);
            selected_y = yp(selected_points);
            
            % Compute selection area borders
            y_upper = y_fit + dist;
            y_lower = y_fit - dist;
            
            tmp = xp(~selected_points);
            mn = mean (tmp);
            st =std(tmp);
            % Plot original data and fitted line
            thrsh = mn+app.edt_n_std.Value*st;
            app.settings.thres = thrsh;
            app.settings.x_fit = x_fit;
            app.settings.y_fit = y_fit;
            app.settings.dist = dist;
            
            app.burst_res(:,8)= app.burst_res(:,4) >= thrsh;
            
            st = std([app.burst_res(:,5);-app.burst_res(:,6)]);
            mn = mean([app.burst_res(:,5);-app.burst_res(:,6)]);
            brsts_idx = find (app.burst_res(:,8) == 1);
            rem_idx_up = find(app.burst_res(brsts_idx,5)> mn+app.edt_outlier.Value*st);
            rem_idx_un = find(-app.burst_res(brsts_idx,6)> mn+app.edt_outlier.Value*st); 
            rem_idx_lp = find(app.burst_res(brsts_idx,5)< mn-st);
            rem_idx_ln = find(-app.burst_res(brsts_idx,6)< mn-st); 
            
            rem_idx = unique([rem_idx_up;rem_idx_un;rem_idx_lp;rem_idx_ln]);
            app.burst_res(brsts_idx(rem_idx),8) = 0;
            
            mn = mean(app.burst_res(:,7));
            st =std(app.burst_res(:,7));
            brsts_idx = find (app.burst_res(:,8) == 1);
            rem_idx_p = find(app.burst_res(brsts_idx,7)> mn+2.5*st);
            rem_idx_n = find(app.burst_res(brsts_idx,7)< mn-2.5*st);
            
            rem_idx = unique([rem_idx_p;rem_idx_n]);
            app.burst_res(brsts_idx(rem_idx),8) = 0;
            tmp_idx = find(diff(app.burst_res(:,2)) == 0);
            app.burst_res(tmp_idx,8) = 0;
            app.burst_res(tmp_idx+1,8) = 0;

        end
    end
    
    methods (Access = private)
        
        function update_axes(app)
            xp = app.burst_res(:,4); % Independent variable
            yp = app.burst_res(:,7); % Dependent variable

            in_section = logical(app.burst_res(:,9));
            over_thres = logical(app.burst_res(:,8));
            tmp_x = xp(in_section & over_thres);
            tmp_y = yp(in_section & over_thres);
            app.settings.s1.XData = tmp_x;
    
            app.settings.s1.YData = tmp_y;
            tmp_x = xp(in_section & ~over_thres);
            tmp_y = yp(in_section & ~over_thres);
            app.settings.s2.XData = tmp_x;
            app.settings.s2.YData = tmp_y;
            tmp_x = xp(~in_section & ~over_thres);
            tmp_y = yp(~in_section & ~over_thres);
            app.settings.s3.XData = tmp_x;
            app.settings.s3.YData = tmp_y;
            tmp_x = xp(~in_section & over_thres);
            tmp_y = yp(~in_section & over_thres);
            app.settings.s4.XData = tmp_x;
            app.settings.s4.YData = tmp_y;
            y_upper = app.settings.y_fit + app.settings.dist;
            y_lower = app.settings.y_fit - app.settings.dist;
            app.settings.p1.XData = app.settings.x_fit;
            app.settings.p1.YData = app.settings.y_fit;
            app.settings.p2.XData = app.settings.x_fit;
            app.settings.p2.YData = y_upper;
            app.settings.p3.XData = app.settings.x_fit;
            app.settings.p3.YData = y_lower;
            app.settings.l.XData = [app.settings.thres app.settings.thres];
            app.settings.l.YData = ylim(app.ax_thresh);
            ylim (app.ax_thresh, app.settings.yl)
            xlim (app.ax_thresh, app.settings.xl)
            
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','r','MarkerFaceColor','g','LineWidth',1.5)
%             hold (app.ax_thresh,'on');
%             tmp_x = xp(in_section & ~over_thres);
%             tmp_y = yp(in_section & ~over_thres);
%             
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','k','MarkerFaceColor','g','LineWidth',1.5)
%             
%             tmp_x = xp(~in_section & ~over_thres);
%             tmp_y = yp(~in_section & ~over_thres);
%             
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','k','MarkerFaceColor','b','LineWidth',1.5)
% 
%             tmp_x = xp(~in_section & over_thres);
%             tmp_y = yp(~in_section & over_thres);
%             
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','r','MarkerFaceColor','b','LineWidth',1.5)
%            
% 
%             y_upper = app.settings.y_fit + app.settings.dist;
%             y_lower = app.settings.y_fit - app.settings.dist;
% 
% 
%             plot(app.ax_thresh,app.settings.x_fit, app.settings.y_fit,'Color',[.7,.7,.7],'LineStyle', '-.', 'LineWidth', 2); % Plot regression line
%             plot(app.ax_thresh,app.settings.x_fit, y_upper,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 2); % Upper boundary
%             plot(app.ax_thresh,app.settings.x_fit, y_lower,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 2); % Lower boundary
%             line (app.ax_thresh,[app.settings.thres app.settings.thres],ylim(app.ax_thresh), "Color",'m')
%             hold (app.ax_thresh,'off');
            
            xlabel(app.ax_thresh,'X');
            ylabel(app.ax_thresh,'Y');
            grid (app.ax_thresh,'on');
%             legend('Data Points', 'Fitted Line', 'Selected Points', 'Selection Borders');


            plot(app.ax_slope,app.burst_res(logical(app.burst_res(:,8)),5),app.burst_res(logical(app.burst_res(:,8)),6),'ro')
            hold (app.ax_slope,'on')
            plot(app.ax_slope,app.burst_res(~logical(app.burst_res(:,8)),5),app.burst_res(~logical(app.burst_res(:,8)),6),'ko')
            xlabel (app.ax_slope,'rise slope')
            ylabel (app.ax_slope,'fall slope')
            hold (app.ax_slope,'off')
           
            tmp1 = diff([app.data.x(int64(app.burst_res(:,1))); app.data.x(int64(app.burst_res(:,2)))],1,1);
            tmp2 = abs(diff([app.data.x(int64(app.burst_res(:,2))); app.data.x(int64(app.burst_res(:,3)))],1,1));
            plot(app.ax_amp,tmp1(logical(app.burst_res(:,8))),tmp2(logical(app.burst_res(:,8))),'ro')
            hold (app.ax_amp,'on')
            plot(app.ax_amp,tmp1(~logical(app.burst_res(:,8))),tmp2(~logical(app.burst_res(:,8))),'ko')
            xlabel (app.ax_amp,'rise amplitude')
            ylabel (app.ax_amp,'fall amplitude')

            dur = max(max(app.burst_res(:,2)-app.burst_res(:,1)),max(app.burst_res(:,3)-app.burst_res(:,2)));
            tmp = nan(2*dur+1,size(app.burst_res,1));
            for i = 1 :size(app.burst_res,1)
                dix = diff(app.burst_res(i,1:3));
                tmp(dur-dix(1)+1:dur+dix(2)+1,i) = app.data.x (app.burst_res(i,2)-dix(1):app.burst_res(i,2)+dix(2));

            end
            plot (app.ax_shapes,(-dur:dur)/app.data.fs,tmp(:,~over_thres),'Color',[.5,.5,.5])
            hold (app.ax_shapes,'on') 
            plot (app.ax_shapes,(-dur:dur)/app.data.fs,tmp(:,over_thres),'r')
            hold (app.ax_shapes,'off') 

        end

        function working(app,on)
            if on
                app.lbl_work.Text = 'Working';
                app.lbl_work.BackgroundColor = 'r';
            else
                app.lbl_work.Text = '';
                app.lbl_work.BackgroundColor = 'none';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            working(app, true)
            drawnow
            app.caller = caller;
            if ~app.caller.sbtn_hb.Value & app.caller.sbtn_resp.Value
                app.settings.type = 2;
            else
                app.settings.type = 1;
            end


            sel = app.caller.data(app.caller.settings.channel_idx.msna).data(app.caller.settings.interval(1,1)/app.caller.data(app.caller.settings.channel_idx.msna).ts(1):app.caller.settings.interval(1,2)/app.caller.data(app.caller.settings.channel_idx.msna).ts(1)); 
            sel(1) = []; 
            Ts_msna = app.caller.data(app.caller.settings.channel_idx.msna).ts(1);
            
            sel=bandpass(sel,[500 5000],1e4);%sel=bandpass(sel,[300 5000],1e4);
            tmp = sqrt(movmean(sel.^2,2001));
            rmsa1 = tmp';
            rmsa2=rmsa1;

            if app.caller.chkbx_detrent_rms.Value
                x=detrend(rmsa2,2);
            else
                x=rmsa2;
            end
            t_msna = (1:length(x))*Ts_msna;
            app.data.fs = 1/app.caller.data(1).ts(1);
            switch app.settings.type
                case 1
                    app.data.evnt_ts = app.caller.hb_res.t_events;
                    app.settings.dur = 3*app.data.fs-1; % 4s +- for resp
                    tmp_data = [x,zeros(1,3*app.data.fs)];
                case 2
                    app.data.evnt_ts = app.caller.resp_res.ts;
                    app.settings.dur = 4*app.data.fs-1;
                    tmp_data = [x,zeros(1,4*app.data.fs)];
                    tmp = find(app.data.evnt_ts < 4);
                    app.data.evnt_ts(tmp) = [];
            end


            app.data.evnt_idx =zeros(size(app.data.evnt_ts));
            %app.data.ridx = zeros(size(app.caller.hb_res.t_events));
            lo = 1;
            hi = numel(t_msna);
            for n = 1:numel(app.data.evnt_ts)%(app.caller.hb_res.t_events)
                b = app.data.evnt_ts(n);%app.caller.hb_res.t_events(n);
                l = lo;
                h = hi;
                while l < h
                    m = floor((l + h) / 2);
                    if t_msna(m) < b
                        l = m + 1;
                    else
                        h = m;
                    end
                end
                app.data.evnt_idx(n) = l;%app.data.ridx(n) = l;
            end 
            
            
            switch app.settings.type
                case 1
                    summ = zeros(app.settings.dur+1,1);
                    for i = 1:length(app.data.evnt_idx)%(app.caller.hb_res.t_events)
                        summ= summ+tmp_data(app.data.evnt_idx(i):app.data.evnt_idx(i)+app.settings.dur)';%(app.data.ridx(i):app.data.ridx(i)+dur)';
                    end
                case 2
                    summ = zeros(2*app.settings.dur+1,1);
                    for i = 1:length(app.data.evnt_idx)%(app.caller.hb_res.t_events)
                        summ= summ+tmp_data(app.data.evnt_idx(i)-app.settings.dur:app.data.evnt_idx(i)+app.settings.dur)';
                    end
            end
            
            summ = summ ./length(app.data.evnt_idx);%(app.caller.hb_res.t_events);

            [b,a] = butter(4,5/(app.data.fs/2),"low");
            sumf = filtfilt(b,a,summ);
            [~,mxi] = max(movmean(sumf,app.data.fs/20));
            switch app.settings.type
                case 1
                    [~,lcs] = findpeaks(-sumf-mean(-sumf),'MinPeakHeight',max(-sumf-mean(-sumf))/10);%findpeaks(-sumf,'MinPeakHeight',min(sumf)/10,"NPeaks",2);
                    [~,idx] = sort(abs(lcs-mxi),"ascend");
                    idx(3:end) = [];
                case 2
                    [~,tmp_lcs] = findpeaks(-sumf(1:mxi)-mean(-sumf),'MinPeakHeight',max(-sumf-mean(-sumf))/10);
                    [~,idx] = sort(abs(tmp_lcs-mxi),"ascend");
                    idx(2:end) = [];
                    lcs =tmp_lcs(idx);
                    [~,tmp_lcs] = findpeaks(-sumf(mxi:end)-mean(-sumf),'MinPeakHeight',max(-sumf-mean(-sumf))/10);
                    [~,idx] = sort(abs(tmp_lcs),"ascend");
                    idx(2:end) = [];
                    lcs(2)= tmp_lcs(idx)+mxi-1;
                    idx = [1,2];
            end
      
            sumts = (1:length(summ))/app.data.fs;
            if app.settings.type == 2
                sumts = sumts-sumts(app.settings.dur+1);
            end

            plot(app.ax_summs, sumts,sumf)
            hold (app.ax_summs,'on') 
            plot (app.ax_summs, sumts(mxi),sumf(mxi),'*g')
            plot (app.ax_summs, sumts(lcs(idx)),sumf(lcs(idx)),'*r')
            yl= ylim (app.ax_summs);
            app.settings.lines(1) = line(app.ax_summs, [sumts(lcs(idx(1))) sumts(lcs(idx(1)))], yl, 'Color','r');
            app.settings.lines(2) = line(app.ax_summs, [sumts(lcs(idx(2))) sumts(lcs(idx(2)))], yl, 'Color','r');

            app.settings.delay = sumts(mxi);
            switch app.settings.type
                case 1
                    app.settings.window = sort(lcs(idx),'ascend');
                    app.settings.window_org = app.settings.window; % adapt for resp
                case 2
                    app.settings.window = sort(lcs-app.settings.dur,'ascend');
                    app.settings.window_org = app.settings.window;
            end
            
            app.data.x = x;
            switch app.settings.type
                case 1
                    [b,a] = butter(2,[0.05,4]/(app.data.fs/2),"bandpass"); 
                case 2
                    [b,a] = ellip(2,0.1,40,[0.05 4]*2/app.data.fs);
            end

            app.data.x_filt = filtfilt(b,a,tmp_data);
            app.data.diff_x_filt = diff(app.data.x_filt).*circshift(diff(app.data.x_filt),1);
            
            get_bursts(app);
            
            select_bursts(app, false);

            app.settings.s1 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','r','MarkerFaceColor','g','LineWidth',1.5);
            hold (app.ax_thresh,'on');
            app.settings.s2 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','k','MarkerFaceColor','g','LineWidth',1.5);

            app.settings.s3 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','k','MarkerFaceColor','b','LineWidth',1.5);
            app.settings.s4 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','r','MarkerFaceColor','b','LineWidth',1.5);

            app.settings.p1 = plot(app.ax_thresh,nan, nan,'Color',[.7,.7,.7],'LineStyle', '-.', 'LineWidth', 3); % Plot regression line
            app.settings.p2 = plot(app.ax_thresh,nan, nan,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 3); % Upper boundary
            app.settings.p3 = plot(app.ax_thresh,nan, nan,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 3); % Lower boundary
            app.settings.l = line (app.ax_thresh,[nan nan],ylim(app.ax_thresh), "Color",'m', 'LineWidth', 3);
            hold (app.ax_thresh,'off');

            update_axes(app)
            working(app, false)
        end

        % Value changed function: extentwindowAEditField
        function extentwindowAEditFieldValueChanged(app, event)
            tmp = int32(app.extentwindowAEditField.Value*app.data.fs);
            app.settings.window = [app.settings.window_org(1)-tmp, app.settings.window_org(2)+tmp];
            
            tmp = (1:app.data.fs*3)/app.data.fs;
            app.settings.lines(1).XData = [tmp(app.settings.window(1)), tmp(app.settings.window(1))];
            app.settings.lines(2).XData = [tmp(app.settings.window(2)), tmp(app.settings.window(2))];
            get_bursts(app);

            select_bursts(app, false);

            update_axes(app)
        end

        % Value changed function: edt_sec_width
        function edt_sec_widthValueChanged(app, event)
            select_bursts(app,true);

            update_axes(app)
            
        end

        % Value changed function: edt_n_std
        function edt_n_stdValueChanged(app, event)
            select_bursts(app,true);

            update_axes(app)
            
        end

        % Button pushed function: confirmcloseButton
        function confirmcloseButtonPushed(app, event)
            t_msna = (1:length(app.data.x))/app.data.fs;
            burst_res.delay = app.settings.delay ;
            burst_res.window = app.settings.window;
            burst_res.x = app.data.x;
            burst_res.ts = [1/app.data.fs, 1/app.data.fs,length(burst_res.x)/app.data.fs];
            burst_res.burst_loc = [app.burst_res(:,1), app.burst_res(:,3)];
            tmp = burst_res.ts(1):burst_res.ts(2):burst_res.ts(3);
            burst_res.t_burst = tmp (app.burst_res(:,2));
%             burst_res.dt_burst = diff(burst_res.t_burst);
            burst_res.use_burst = logical(app.burst_res(:,8));
            burst_res.use_burst(:,2) =true(size(burst_res.use_burst(:,1))) ;
            n_brsts = size(app.burst_res,1);
            burst_res.burst_int = nan(1,n_brsts);
            burst_res.burst_amp = nan(1,n_brsts);
            for i = 1 : n_brsts
                tmp_d = [t_msna(app.burst_res(i,1):app.burst_res(i,3))',app.data.x(app.burst_res(i,1):app.burst_res(i,3))'*1000];
                tmp_d(:,2) = tmp_d(:,2) - min (tmp_d(:,2));
            
                burst_res.burst_amp(i)  = max(tmp_d(:,2)); %%% ampl
                burst_res.burst_int(i) = trapz(tmp_d(:,1),tmp_d(:,2)); %% int
            end
            burst_res.burst_dur = (app.burst_res(:,3)-app.burst_res(:,1))/app.data.fs;
            burst_res.burst_latency = app.burst_res(:,7);
            burst_res.type = 1;
            burst_res.delay = app.settings.delay;
            burst_res.window = app.settings.window;
            burst_res.window_org = app.settings.window_org;
            import_bursts(app.caller,burst_res)
            delete(app)
        end

        % Value changed function: sldr_xp
        function sldr_xpValueChanged(app, event)
            select_bursts(app, true);

            update_axes(app)
            
        end

        % Value changing function: sldr_xp
        function sldr_xpValueChanging(app, event)
            
%             app.settings.y_fit = polyval([app.sldr_xp.Value ,app.sldr_yp.Value], app.settings.x_fit);
%             y_upper = app.settings.y_fit + app.settings.dist;
%             y_lower = app.settings.y_fit - app.settings.dist;
%             app.settings.p1.XData = app.settings.x_fit;
%             app.settings.p1.YData = app.settings.y_fit;
%             app.settings.p2.XData = app.settings.x_fit;
%             app.settings.p2.YData = y_upper;
%             app.settings.p3.XData = app.settings.x_fit;
%             app.settings.p3.YData = y_lower;
%             drawnow
        end

        % Value changed function: sldr_yp
        function sldr_ypValueChanged(app, event)
            select_bursts(app, true);

            update_axes(app)
            
        end

        % Value changing function: sldr_yp
        function sldr_ypValueChanging(app, event)

%             app.settings.y_fit = polyval([app.sldr_xp.Value ,app.sldr_yp.Value], app.settings.x_fit);
%             y_upper = app.settings.y_fit + app.settings.dist;
%             y_lower = app.settings.y_fit - app.settings.dist;
%             app.settings.p1.XData = app.settings.x_fit;
%             app.settings.p1.YData = app.settings.y_fit;
%             app.settings.p2.XData = app.settings.x_fit;
%             app.settings.p2.YData = y_upper;
%             app.settings.p3.XData = app.settings.x_fit;
%             app.settings.p3.YData = y_lower;
%             drawnow
            
        end

        % Value changed function: edt_outlier
        function edt_outlierValueChanged(app, event)
            select_bursts(app, true);

            update_axes(app)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_summs = uiaxes(app.UIFigure);
            xlabel(app.ax_summs, '[s]')
            ylabel(app.ax_summs, '[mV]')
            zlabel(app.ax_summs, 'Z')
            app.ax_thresh = uiaxes(app.UIFigure);
            xlabel(app.ax_thresh, {'Amplitude'; ''})
            ylabel(app.ax_thresh, 'Delay')
            zlabel(app.ax_thresh, 'Z')
            app.ax_shapes = uiaxes(app.UIFigure);
            xlabel(app.ax_shapes, '[s]')
            ylabel(app.ax_shapes, '[mV]')
            zlabel(app.ax_shapes, 'Z')
            app.ax_amp = uiaxes(app.UIFigure);
            xlabel(app.ax_amp, 'X')
            ylabel(app.ax_amp, 'Y')
            zlabel(app.ax_amp, 'Z')
            app.ax_slope = uiaxes(app.UIFigure);
            xlabel(app.ax_slope, 'X')
            ylabel(app.ax_slope, 'Y')
            zlabel(app.ax_slope, 'Z')
            app.extentwindowAEditFieldLabel = uilabel(app.UIFigure);
            app.extentwindowAEditFieldLabel.Text = 'extent window (A)';
            app.extentwindowAEditField = uieditfield(app.UIFigure, 'numeric');
            app.extentwindowAEditField.ValueChangedFcn = createCallbackFcn(app, @extentwindowAEditFieldValueChanged, true);
            app.sectionwidthBLabel = uilabel(app.UIFigure);
            app.sectionwidthBLabel.Text = 'section  width (B)';
            app.edt_sec_width = uieditfield(app.UIFigure, 'numeric');
            app.edt_sec_width.Limits = [0 Inf];
            app.edt_sec_width.ValueChangedFcn = createCallbackFcn(app, @edt_sec_widthValueChanged, true);
            app.edt_sec_width.Value = 0.1;
            app.nstdCLabel = uilabel(app.UIFigure);
            app.nstdCLabel.Text = 'n* std (C)';
            app.edt_n_std = uieditfield(app.UIFigure, 'numeric');
            app.edt_n_std.ValueChangedFcn = createCallbackFcn(app, @edt_n_stdValueChanged, true);
            app.edt_n_std.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pics', 'scheme.png');
            app.confirmcloseButton = uibutton(app.UIFigure, 'push');
            app.confirmcloseButton.ButtonPushedFcn = createCallbackFcn(app, @confirmcloseButtonPushed, true);
            app.confirmcloseButton.Text = 'confirm / close';
            app.slopeLabel = uilabel(app.UIFigure);
            app.slopeLabel.Text = 'D';
            app.sldr_xp = uislider(app.UIFigure);
            app.sldr_xp.ValueChangedFcn = createCallbackFcn(app, @sldr_xpValueChanged, true);
            app.sldr_xp.ValueChangingFcn = createCallbackFcn(app, @sldr_xpValueChanging, true);
            app.dyLabel = uilabel(app.UIFigure);
            app.dyLabel.Text = 'E';
            app.sldr_yp = uislider(app.UIFigure);
            app.sldr_yp.ValueChangedFcn = createCallbackFcn(app, @sldr_ypValueChanged, true);
            app.sldr_yp.ValueChangingFcn = createCallbackFcn(app, @sldr_ypValueChanging, true);
            app.lbl_work = uilabel(app.UIFigure);
            app.lbl_work.Text = '';
            app.outliernstdEditFieldLabel = uilabel(app.UIFigure);
            app.outliernstdEditFieldLabel.Text = 'outlier n*std';
            app.edt_outlier = uieditfield(app.UIFigure, 'numeric');
            app.edt_outlier.ValueChangedFcn = createCallbackFcn(app, @edt_outlierValueChanged, true);
            app.edt_outlier.Value = 9;
            app.RegressionlineYDXELabel = uilabel(app.UIFigure);
            app.RegressionlineYDXELabel.Text = 'Regression line Y = D*X +E';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = burst_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end